classdef AppDesigner_DAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        continuousDataAxes           matlab.ui.control.UIAxes
        scope2Axes                   matlab.ui.control.UIAxes
        stimAxes                     matlab.ui.control.UIAxes
        capturedDataAxes             matlab.ui.control.UIAxes
        runStateButton               matlab.ui.control.StateButton
        captureStateButton           matlab.ui.control.StateButton
        stopDaqButton                matlab.ui.control.Button
        exitButton                   matlab.ui.control.Button
        FreqHzEditFieldLabel         matlab.ui.control.Label
        fscvFreq                     matlab.ui.control.NumericEditField
        ScanRVsEditFieldLabel        matlab.ui.control.Label
        fscvScanRate                 matlab.ui.control.NumericEditField
        UpperLimVEditFieldLabel      matlab.ui.control.Label
        fscvUpperLimit               matlab.ui.control.NumericEditField
        LowerLimVEditFieldLabel      matlab.ui.control.Label
        fscvLowerLimit               matlab.ui.control.NumericEditField
        DisplayRangesEditFieldLabel  matlab.ui.control.Label
        displayRange                 matlab.ui.control.NumericEditField
        ChEditField_2Label           matlab.ui.control.Label
        scope1Channel                matlab.ui.control.NumericEditField
        ChEditFieldLabel             matlab.ui.control.Label
        scope2Channel                matlab.ui.control.NumericEditField
        FSCVParametersLabel          matlab.ui.control.Label
        PulseVoltsEditFieldLabel     matlab.ui.control.Label
        stimVolts                    matlab.ui.control.NumericEditField
        RecordSettingsLabel          matlab.ui.control.Label
        StimulationParametersLabel   matlab.ui.control.Label
        DelaymsEditFieldLabel        matlab.ui.control.Label
        FreqHzEditFieldLabel_2       matlab.ui.control.Label
        stimFreq                     matlab.ui.control.NumericEditField
        NumofPulsesEditFieldLabel    matlab.ui.control.Label
        stimNumPulses                matlab.ui.control.NumericEditField
        OnsetPulsessEditFieldLabel   matlab.ui.control.Label
        stimOnsetOfPulses            matlab.ui.control.NumericEditField
        stimDelay                    matlab.ui.control.NumericEditField
        PulseWidthmsEditFieldLabel   matlab.ui.control.Label
        stimPulseWidth               matlab.ui.control.NumericEditField
        TriggerDropDownLabel         matlab.ui.control.Label
        stimTrigger                  matlab.ui.control.DropDown
        RecordTimesEditFieldLabel    matlab.ui.control.Label
        recordTime                   matlab.ui.control.NumericEditField
        FileNameEditFieldLabel       matlab.ui.control.Label
        recordFileName               matlab.ui.control.EditField
        recordContinuousButton       matlab.ui.control.StateButton
        stimEnableButton             matlab.ui.control.StateButton
        stimDisableButton            matlab.ui.control.StateButton
        StatusLabel_2                matlab.ui.control.Label
        statusText                   matlab.ui.control.EditField
        holdStateButton              matlab.ui.control.StateButton
        scope1Axes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        parameters;
        display;
        daq;
    end
    
    methods (Access = private)
        
        function enableButtonsOnGUI(app)
            app.runStateButton.Enable = "on";
            app.captureStateButton.Enable = "on";
            app.stopDaqButton.Enable = "on";
            app.exitButton.Enable = "on";
            
            app.stimEnableButton.Enable = "on";
            app.stimDisableButton.Enable = "on";
            app.recordContinuousButton.Enable = "on";
        end
    end
    

    methods (Access = public)
        
        function stopDaq(app)
            app.daq.stopAcquisition();
        end

        function setMessage(app, message)
            app.statusText.Value = message;
        end

        function setFileName(app, fileName)
            app.recordFileName.Value = fileName;
        end

        function fileName = getFileName(app)
            fileName = app.recordFileName.Value; 
        end

        function state = getRun(app)
            state = app.runStateButton.Value; 
        end

        function state = getCapture(app)
            state = app.captureStateButton.Value;
        end

        function state = getContinuousRecord(app)
            state = app.recordContinuousButton.Value;
        end    

        function state = getHold(app)
            state = app.holdStateButton.Value;
        end    

        function resetButtons(app)
            app.runStateButton.Value = 0;
            app.captureStateButton.Value = 0; 
            app.recordContinuousButton.Value = 0;
        end    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 
            format long;
            
            app.statusText.Value = 'Initializing DAQ...';
                 
            sampleRate = 25000;

            % initialize state, parameters + display
            app.parameters = Parameters(app, sampleRate);
            app.display = DisplayPlots(app, app.parameters);

            % initialize DAQ            
            app.daq = DataAcquisition(app, app.parameters, ...
                                app.display, sampleRate);
            
            % enable GUI buttons
            app.enableButtonsOnGUI();
            app.statusText.Value = 'DAQ Ready';
                        
        end

        % Value changed function: runStateButton
        function runStateButtonValueChanged(app, event)
 
            if (app.runStateButton.Value == 1)
                app.daq.startAcquisition();
            end
            
        end

        % Value changed function: captureStateButton
        function captureStateButtonValueChanged(app, event)
            
            if (app.captureStateButton.Value == 1)            
                app.daq.startAcquisition();
            end
            
        end

        % Button pushed function: stopDaqButton
        function stopDaqButtonPushed(app, event)
            
            app.daq.stopAcquisition();
            
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
            
            app.daq.stopAcquisition();
            close(app.UIFigure);

        end

        % Value changed function: stimEnableButton
        function stimEnableButtonValueChanged(app, event)


        end

        % Value changed function: stimDisableButton
        function stimDisableButtonValueChanged(app, event)


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                    
            app.daq.stopAcquisition();
            delete(app.UIFigure);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.continuousDataAxes = uiaxes(app.UIFigure);
            title(app.continuousDataAxes, 'Continuous Data')
            xlabel(app.continuousDataAxes, 'time(s)')
            ylabel(app.continuousDataAxes, 'V')
            zlabel(app.continuousDataAxes, 'Z')
            app.scope2Axes = uiaxes(app.UIFigure);
            title(app.scope2Axes, 'Scope2')
            xlabel(app.scope2Axes, 'time(s)')
            ylabel(app.scope2Axes, 'V')
            zlabel(app.scope2Axes, 'Z')
            app.stimAxes = uiaxes(app.UIFigure);
            title(app.stimAxes, 'Simulation')
            xlabel(app.stimAxes, 'time(s)')
            ylabel(app.stimAxes, 'V')
            zlabel(app.stimAxes, 'Z')
            app.capturedDataAxes = uiaxes(app.UIFigure);
            title(app.capturedDataAxes, 'Captured Data')
            xlabel(app.capturedDataAxes, 'time(s)')
            ylabel(app.capturedDataAxes, 'V')
            zlabel(app.capturedDataAxes, 'Z')
            app.runStateButton = uibutton(app.UIFigure, 'state');
            app.runStateButton.ValueChangedFcn = createCallbackFcn(app, @runStateButtonValueChanged, true);
            app.runStateButton.Enable = 'off';
            app.runStateButton.Text = 'Run';
            app.captureStateButton = uibutton(app.UIFigure, 'state');
            app.captureStateButton.ValueChangedFcn = createCallbackFcn(app, @captureStateButtonValueChanged, true);
            app.captureStateButton.Enable = 'off';
            app.captureStateButton.Text = 'CaptureButton';
            app.stopDaqButton = uibutton(app.UIFigure, 'push');
            app.stopDaqButton.ButtonPushedFcn = createCallbackFcn(app, @stopDaqButtonPushed, true);
            app.stopDaqButton.Enable = 'off';
            app.stopDaqButton.Text = 'Stop DAQ';
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Enable = 'off';
            app.exitButton.Text = 'Exit';
            app.FreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.fscvFreq = uieditfield(app.UIFigure, 'numeric');
            app.fscvFreq.Value = 10;
            app.ScanRVsEditFieldLabel = uilabel(app.UIFigure);
            app.ScanRVsEditFieldLabel.Text = 'Scan R. (V/s)';
            app.fscvScanRate = uieditfield(app.UIFigure, 'numeric');
            app.fscvScanRate.Value = 400;
            app.UpperLimVEditFieldLabel = uilabel(app.UIFigure);
            app.UpperLimVEditFieldLabel.Text = 'Upper Lim. (V)';
            app.fscvUpperLimit = uieditfield(app.UIFigure, 'numeric');
            app.fscvUpperLimit.Value = 1.4;
            app.LowerLimVEditFieldLabel = uilabel(app.UIFigure);
            app.LowerLimVEditFieldLabel.Text = 'Lower Lim. (V) ';
            app.fscvLowerLimit = uieditfield(app.UIFigure, 'numeric');
            app.fscvLowerLimit.Value = -0.3;
            app.DisplayRangesEditFieldLabel = uilabel(app.UIFigure);
            app.DisplayRangesEditFieldLabel.Text = 'Display Range (s)';
            app.displayRange = uieditfield(app.UIFigure, 'numeric');
            app.displayRange.Value = 1;
            app.ChEditField_2Label = uilabel(app.UIFigure);
            app.ChEditField_2Label.Text = 'Ch';
            app.scope1Channel = uieditfield(app.UIFigure, 'numeric');
            app.scope1Channel.Value = 1;
            app.ChEditFieldLabel = uilabel(app.UIFigure);
            app.ChEditFieldLabel.Text = 'Ch';
            app.scope2Channel = uieditfield(app.UIFigure, 'numeric');
            app.scope2Channel.Value = 2;
            app.FSCVParametersLabel = uilabel(app.UIFigure);
            app.FSCVParametersLabel.Text = 'FSCV Parameters';
            app.PulseVoltsEditFieldLabel = uilabel(app.UIFigure);
            app.PulseVoltsEditFieldLabel.Text = 'Pulse  Volts';
            app.stimVolts = uieditfield(app.UIFigure, 'numeric');
            app.stimVolts.Value = 5;
            app.RecordSettingsLabel = uilabel(app.UIFigure);
            app.RecordSettingsLabel.Text = 'Record Settings';
            app.StimulationParametersLabel = uilabel(app.UIFigure);
            app.StimulationParametersLabel.Text = 'Stimulation Parameters';
            app.DelaymsEditFieldLabel = uilabel(app.UIFigure);
            app.DelaymsEditFieldLabel.Text = 'Delay (ms)';
            app.FreqHzEditFieldLabel_2 = uilabel(app.UIFigure);
            app.FreqHzEditFieldLabel_2.Text = 'Freq (Hz)';
            app.stimFreq = uieditfield(app.UIFigure, 'numeric');
            app.stimFreq.Value = 60;
            app.NumofPulsesEditFieldLabel = uilabel(app.UIFigure);
            app.NumofPulsesEditFieldLabel.Text = 'Num of Pulses';
            app.stimNumPulses = uieditfield(app.UIFigure, 'numeric');
            app.stimNumPulses.Value = 24;
            app.OnsetPulsessEditFieldLabel = uilabel(app.UIFigure);
            app.OnsetPulsessEditFieldLabel.Text = 'Onset Pulses (s)';
            app.stimOnsetOfPulses = uieditfield(app.UIFigure, 'numeric');
            app.stimOnsetOfPulses.Value = 10;
            app.stimDelay = uieditfield(app.UIFigure, 'numeric');
            app.stimDelay.Value = 0.1;
            app.PulseWidthmsEditFieldLabel = uilabel(app.UIFigure);
            app.PulseWidthmsEditFieldLabel.Text = 'Pulse Width (ms)';
            app.stimPulseWidth = uieditfield(app.UIFigure, 'numeric');
            app.stimPulseWidth.Value = 2;
            app.TriggerDropDownLabel = uilabel(app.UIFigure);
            app.TriggerDropDownLabel.Text = 'Trigger';
            app.stimTrigger = uidropdown(app.UIFigure);
            app.stimTrigger.Items = {'mono', 'bi'};
            app.stimTrigger.Value = 'mono';
            app.RecordTimesEditFieldLabel = uilabel(app.UIFigure);
            app.RecordTimesEditFieldLabel.Text = 'Record Time (s)';
            app.recordTime = uieditfield(app.UIFigure, 'numeric');
            app.recordTime.Value = 5;
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.recordFileName = uieditfield(app.UIFigure, 'text');
            app.recordFileName.Value = 'test_capture_100';
            app.recordContinuousButton = uibutton(app.UIFigure, 'state');
            app.recordContinuousButton.Enable = 'off';
            app.recordContinuousButton.Text = 'continuous';
            app.stimEnableButton = uibutton(app.UIFigure, 'state');
            app.stimEnableButton.ValueChangedFcn = createCallbackFcn(app, @stimEnableButtonValueChanged, true);
            app.stimEnableButton.Enable = 'off';
            app.stimEnableButton.Text = 'enable';
            app.stimDisableButton = uibutton(app.UIFigure, 'state');
            app.stimDisableButton.ValueChangedFcn = createCallbackFcn(app, @stimDisableButtonValueChanged, true);
            app.stimDisableButton.Enable = 'off';
            app.stimDisableButton.Text = 'disable';
            app.StatusLabel_2 = uilabel(app.UIFigure);
            app.StatusLabel_2.Text = 'Status';
            app.statusText = uieditfield(app.UIFigure, 'text');
            app.holdStateButton = uibutton(app.UIFigure, 'state');
            app.holdStateButton.Text = 'Hold Scope';
            app.scope1Axes = uiaxes(app.UIFigure);
            title(app.scope1Axes, 'Scope1')
            xlabel(app.scope1Axes, 'time(s)')
            ylabel(app.scope1Axes, 'V')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppDesigner_DAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end